classdef image_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UITable        matlab.ui.control.Table
        Label_3        matlab.ui.control.Label
        Image3_2       matlab.ui.control.Image
        Label_2        matlab.ui.control.Label
        Image3_3       matlab.ui.control.Image
        Label          matlab.ui.control.Label
        Image3_1       matlab.ui.control.Image
        OutputLabel    matlab.ui.control.Label
        InputLabel     matlab.ui.control.Label
        MattingButton  matlab.ui.control.Button
        Image2         matlab.ui.control.Image
        Image          matlab.ui.control.Image
        TrimapButton   matlab.ui.control.Button
        RGBButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        RGBImagePath string % RGB Image Path
        TrimapImagePath string % Trimap Image Path
        Laplacian_alpha double
        Bayesian_alpha double
        gt double
        GT double
        MSE double
        SAD double
        Gradient_Error double
        Connectivity_Error double
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RGBButton
        function RGBButtonPushed(app, event)
            % Open a file selection dialog box, allowing the user to choose an image file
            [rgb_img, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files (*.jpg, *.png, *.jpeg)'}, 'Select an image file');
            % Check if the user actually chose a file (i.e., didn't click cancel)
            if isequal(rgb_img, 0) || isequal(path, 0)
                disp('User pressed cancel')
            else
                % Construct the full file path
                rgb_filename = fullfile(path, rgb_img);
                app.RGBImagePath = rgb_filename;
                app.Image.ImageSource = rgb_filename;
                disp(['User selected ', rgb_filename]);
            end
        end

        % Button pushed function: TrimapButton
        function TrimapButtonPushed(app, event)
            % Open a file selection dialog box, allowing the user to choose an image file
            [trimap, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files (*.jpg, *.png, *.jpeg)'}, 'Select an image file');
            % Check if the user actually chose a file (i.e., didn't click cancel)
            if isequal(trimap, 0) || isequal(path, 0)
                disp('User pressed cancel')
            else
                % Construct the full file path
                trimap_filename = fullfile(path, trimap);
                app.TrimapImagePath = trimap_filename;
                app.Image2.ImageSource = trimap_filename;
                disp(['User selected ', trimap_filename]);
            end
        end

        % Button pushed function: MattingButton
        function MattingButtonPushed(app, event)
            % Ensure both paths have been selected
            if isempty(app.RGBImagePath) || isempty(app.TrimapImagePath)
                % Alert the user if either image has not been selected
                uialert(app.UIFigure, 'Please select both RGB and Trimap images first.', 'Missing Information');
                return; % Exit the function if the necessary images haven't been selected
            end

            % Set the variables that the script will use
            levels_num = 3; % Set according to your requirements
            active_levels_num = 1; % Set according to your requirements
            addpath('./laplacian/');
            app.Laplacian_alpha = runLaplacian(app.RGBImagePath, app.TrimapImagePath, levels_num, active_levels_num);
            imwrite(app.Laplacian_alpha, 'Image3_3.png');
            app.Image3_3.ImageSource = 'Image3_3.png';

            addpath('./');
            app.Bayesian_alpha = calculate_alpha(app.RGBImagePath, app.TrimapImagePath, 8 ,10);
            imwrite(app.Bayesian_alpha, 'Image3_1.png');
            app.Image3_1.ImageSource = 'Image3_1.png';
            [~, name, ext] = fileparts(app.RGBImagePath);
            fileName = name + ext;
            
            gt_path = fullfile('../dataset/gt_training_lowres/', fileName);
            app.gt = double(imread(gt_path));
            imwrite(app.gt, 'Image3_2.png');
            app.Image3_2.ImageSource = 'Image3_2.png';
            %disp(size(gt));
            [MSE_value,SAD_value,gradient_error,connectivity_error] = evaluation(app.Laplacian_alpha, app.gt);
            data = table(fileName', MSE_value', SAD_value', gradient_error', connectivity_error');
            app.UITable.Data = data;
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.Image.ImageSource = '../dataset/input_training_lowres/GT01.png';
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            app.Image2.ImageSource = '../dataset/trimap_training_lowres/Trimap1/GT01.png';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RGBButton = uibutton(app.UIFigure, 'push');
            app.RGBButton.ButtonPushedFcn = createCallbackFcn(app, @RGBButtonPushed, true);
            app.RGBButton.Text = 'RGB';
            app.TrimapButton = uibutton(app.UIFigure, 'push');
            app.TrimapButton.ButtonPushedFcn = createCallbackFcn(app, @TrimapButtonPushed, true);
            app.TrimapButton.Text = 'Trimap';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'INPUT01.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'TRI01.png');
            app.MattingButton = uibutton(app.UIFigure, 'push');
            app.MattingButton.ButtonPushedFcn = createCallbackFcn(app, @MattingButtonPushed, true);
            app.MattingButton.Text = 'Matting';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input:';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.Image3_1 = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Bayesian result';
            app.Image3_3 = uiimage(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Laplacian result';
            app.Image3_2 = uiimage(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Ground truth';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Image Name'; 'MSE'; 'SAD'; 'Gradient Error'; 'Connectivity Error'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end